classdef optimal_thickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UnitsDropDownLabel          matlab.ui.control.Label
        UnitsDropDown               matlab.ui.control.DropDown
        ThicknessButton             matlab.ui.control.Button
        ThicknessEditField          matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel    matlab.ui.control.Label
        PhaseShiftEditField         matlab.ui.control.NumericEditField
        OptimalThicknessLabel       matlab.ui.control.Label
        MaterialsDropDownLabel      matlab.ui.control.Label
        MaterialsDropDown           matlab.ui.control.DropDown
        WavelengthnmEditFieldLabel  matlab.ui.control.Label
        WavelengthnmEditField       matlab.ui.control.NumericEditField
        NumericalApertureEditFieldLabel  matlab.ui.control.Label
        NumericalApertureEditField  matlab.ui.control.NumericEditField
        PitchnmEditFieldLabel       matlab.ui.control.Label
        PitchnmEditField            matlab.ui.control.NumericEditField
        ImageAxis                   matlab.ui.control.UIAxes
        ImageButton                 matlab.ui.control.Button
        PredictedImageAxes          matlab.ui.control.UIAxes
        PredictedImageLabel         matlab.ui.control.Label
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        materials 
        deltas
        betas
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         [materials, deltas, betas] = parseIndexFromDataFile('data/euv-materials.xlsx');

            % Create MaterialsDropDown
            app.MaterialsDropDown.Items = materials;
            
            app.materials = materials;
            app.deltas = deltas;
            app.betas = betas;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % do nothing
        end

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
                        
            % determine what delta corresponds to that material
            delta = app.deltas(strcmp(app.materials, app.MaterialsDropDown.Value));
            
            % determine real part of index of refraction
            n2 = 1 + delta;
            
            % 1) ask what the phase shift value is
            phaseShift = app.PhaseShiftEditField.Value;
            
            % 2) figure out what the correct unit is, and compute
            % phaseshift in correct unit
            unit = app.UnitsDropDown.Value;
            
            if strcmp(unit, 'radians') 
                phaseShift = app.PhaseShiftEditField.Value;
            elseif strcmp(unit, 'degrees')
                phaseShift = app.PhaseShiftEditField.Value*pi/180;
            elseif strcmp(unit, 'waves')
                phaseShift = app.PhaseShiftEditField.Value*2*pi;
            end
            
            % 3) compute thickness using correct phase shift unit          
            n1 = 1; % vacuum
            theta1 = 6*pi/180; % 6 degrees in radians
            theta2 = asin(n1/n2*sin(theta1));
            d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
            
            % if d is negative, then we know it's too small and must find
            % the next solution that is positive
            counter = 0;
            while (d<0)
                phaseShift = phaseShift-(2*pi);
                d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
                counter = counter+1;
                if (counter>50) % likely in an infinite loop
                    break
                end
            end
            
            % display optimal thickness
            app.ThicknessEditField.Value = d;
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %{ 
            conversion factor:
            1 pixel = lambda/(5*pitch)
            %}
            
            % define square function
            
            square = @(x).5*sign(sin(x))+.5;
            
            x = linspace(0, 5*app.PitchnmEditField.Value, 1000);
            
            % convert to cutoff to pixel value
            cutoff = app.NumericalApertureEditField.Value/(app.WavelengthnmEditField.Value/app.PitchnmEditField.Value)*5;
            
            % define diffraction grating with given parameters
            grating = square(x*2*pi/app.PitchnmEditField.Value)+1;
            
            % plot diffractiong grating
            plot(app.ImageAxis, x, grating)
            
            % take FFT of grating
            abs(fftshift(fft(grating)));
            
            % define aperture function
            apertureFxn = zeros(1, 1000);
            apertureFxn(501-cutoff:501+cutoff) = 1;
            
            % multiply FFT of grating with aperture function
            image = fftshift(fft(grating)).*apertureFxn;
            
            % take inverse FFT of image and plot
            plot(app.PredictedImageAxes, x, abs((ifft(ifftshift((image))))).^2)
            
            %{
            - y axis not centered?
            - doesnt give back diffraction grating for limiting cases
            
            %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'radians', 'degrees', 'waves', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'radians';
            app.ThicknessButton = uibutton(app.UIFigure, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalThicknessLabel = uilabel(app.UIFigure);
            app.OptimalThicknessLabel.Text = 'Optimal Thickness';
            app.MaterialsDropDownLabel = uilabel(app.UIFigure);
            app.MaterialsDropDownLabel.Text = 'Materials';
            app.MaterialsDropDown = uidropdown(app.UIFigure);
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericalApertureEditFieldLabel = uilabel(app.UIFigure);
            app.NumericalApertureEditFieldLabel.Text = 'Numerical Aperture';
            app.NumericalApertureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchnmEditFieldLabel = uilabel(app.UIFigure);
            app.PitchnmEditFieldLabel.Text = 'Pitch (nm)';
            app.PitchnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageAxis = uiaxes(app.UIFigure);
            title(app.ImageAxis, 'Image')
            xlabel(app.ImageAxis, 'X')
            ylabel(app.ImageAxis, 'Y')
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.PredictedImageAxes = uiaxes(app.UIFigure);
            title(app.PredictedImageAxes, 'Predicted Image')
            xlabel(app.PredictedImageAxes, 'X')
            ylabel(app.PredictedImageAxes, 'Y')
            app.PredictedImageLabel = uilabel(app.UIFigure);
            app.PredictedImageLabel.Text = 'Predicted Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '___________________________________________________________________________________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimal_thickness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end